classdef PSD_EstimatorV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSDEstimatorsbyDanielMatanUIFigure  matlab.ui.Figure
        XnlengthNEditFieldLabel_2  matlab.ui.control.Label
        alphabeta                  matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        Dval                       matlab.ui.control.NumericEditField
        DLabel                     matlab.ui.control.Label
        Kval                       matlab.ui.control.NumericEditField
        KLabel                     matlab.ui.control.Label
        Lval                       matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        WindowToggle               matlab.ui.control.Switch
        WindowType                 matlab.ui.control.DropDown
        WindowTypeDropDownLabel    matlab.ui.control.Label
        CalcX                      matlab.ui.control.Button
        NEditField                 matlab.ui.control.NumericEditField
        NEditFieldLabel            matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        TFexample                  matlab.ui.control.TextArea
        DenumText                  matlab.ui.control.EditField
        DenominatorEditFieldLabel  matlab.ui.control.Label
        LOG                        matlab.ui.control.TextArea
        NumiText                   matlab.ui.control.EditField
        NumeratorEditFieldLabel    matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        WindowsDispFreq            matlab.ui.control.UIAxes
        WindowsDispTime            matlab.ui.control.UIAxes
        PSDest                     matlab.ui.control.UIAxes
        RealPSD                    matlab.ui.control.UIAxes
        synthX                     matlab.ui.control.UIAxes
        zpwplot                    matlab.ui.control.UIAxes
        zpplot                     matlab.ui.control.UIAxes
    end

    properties (Access = private)

        numi;
        denumi;
        opt;
        z;
        p;
        a;
        b;
        sys_zpk;
        sys_tf;
        xn;
        L;
        D;
        K;
        N;
hweltch;
    end

    methods (Access = private)
        function calcZP(app)
            if(app.opt)
                %User enters poles and zeros
                zuser = [app.numi];
                puser = [app.denumi];

                zlen = length(zuser);
                plen = length(puser);

                %add poles or zeros at (0,0) so the system will be casual
                if zlen > plen %MA or ARMA
                    p = [zeros(1,zlen-plen)];
                    z = zuser;
                    p = [p,puser];
                else %AR or ARMA
                    z = [zeros(1,plen-zlen)];
                    z = [z,zuser];
                    p = puser;
                end
                app.z = z;
                app.p = p;
                app.a = poly(app.p); %transform roots into poly form
                app.b = poly(app.z); %transform roots into poly form

            else
                %or user enters transfer function b,a
                %MA
                buser = [app.numi];
                auser = [app.denumi];
                %AR

                blen = length(buser);
                alen = length(auser);

                if blen > alen %MA
                    a = [zeros(1,blen-alen)];
                    a = [auser,a];
                    b = buser;
                else %AR
                    b = [zeros(1,alen-blen)];
                    b = [buser,b];
                    a = auser;
                end
                app.a = a;
                app.b = b;
                app.p = roots(a);
                app.z = roots(b);

            end
        end


        function resetVars(app)
            app.numi = [];
            app.denumi = [];
            %app.opt = [];
            app.z = [];
            app.p = [];
            app.a = [];
            app.b = [];
            app.sys_zpk = [];
            app.sys_tf = [];
            app.xn = [];
            app.hweltch = [];
            app.N = []
        end


        function selectWindow(app)

            window_name = app.WindowType.Value;
            window_shape = app.WindowToggle.Value;
            %app.L = app.Lval.Value
            %app.D = app.Dval.Value
            validateSizes(app);

            app.alphabeta.Visible = "off";
            app.alphabeta.Enable = "off";

            switch window_name
                case "Hamming"
                    app.WindowToggle.Enable = "on";
                    windowtype = hamming(app.L,window_shape);
                case "Blackman"
                    app.WindowToggle.Enable = "on";
                    windowtype = blackman(app.L,window_shape);
                case "Hann"
                    app.WindowToggle.Enable = "on";
                    windowtype = hann(app.L,window_shape);
                case "Barthann"
                    app.WindowToggle.Enable = "off";
                    windowtype = barthannwin(app.L);
                case "Rectangle"
                    app.WindowToggle.Enable = "off";
                    windowtype = rectwin(app.L);
                case "Kaiser"
                    app.alphabeta.Visible = "on";
                    app.alphabeta.Enable = "on";

                    app.WindowToggle.Enable = "off";
                    windowtype = kaiser(app.L,app.alphabeta.Value);
                case "Gausswin"
                    app.alphabeta.Visible = "on";
                    app.alphabeta.Enable = "on";
                    app.WindowToggle.Enable = "off";
                    windowtype = gausswin(app.L,app.alphabeta.Value);
                otherwise
                    warning('Unexpected plot type. No plot created.');
            end

            if ~isempty(app.hweltch)
                delete(app.hweltch);
            end

            hold(app.PSDest,"on");
            [psd_welch,ww] = pwelch(app.xn,windowtype,app.D);
            app.hweltch = plot(app.PSDest,ww,10*log10(2*pi*psd_welch),'g','DisplayName','Weltch');
            hold(app.PSDest,"off");


            plot(app.WindowsDispTime,windowtype,'DisplayName','Time Domain');
            xlabel(app.WindowsDispTime,'Samples');
            ylabel(app.WindowsDispTime,'Amplitude');
            title(app.WindowsDispTime,"Time Domain");
            grid(app.WindowsDispTime,"on");

            [hh,ww] = freqz(windowtype);
            w = ww./pi;
            y = 20*log10(abs(hh));
            plot(app.WindowsDispFreq,w,y,'DisplayName','Frequency Domain');
            title(app.WindowsDispFreq,"Frequency Domain");
            xlabel(app.WindowsDispFreq,'Normalized Frequency (\times\pi rad/sample)');
            ylabel(app.WindowsDispFreq,'Magnitude (dB)');
            grid(app.WindowsDispFreq,'on');

        end
        function [N,L,D] = validateSizes(app)
            N = app.NEditField.Value; %total size of signal
            L = app.Lval.Value; %L<=N , size of sub group
            D = app.Dval.Value; %overlap D<=L/2
            %add weak protection
            if D>L/2; D=L/2; end
            if L>N; L=N; end
            app.N = N;
            app.L = L;
            app.D = D;
            
            app.Lval.Value = L;
            app.Dval.Value = D;
        
        end
        
        function updateK(app)
            
            [N,L,D] = validateSizes(app);
            app.Kval.Value = floor((N+D-L)/D);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switchvalue = app.Switch.Value;
            if switchvalue == "Transfer Function"
                app.opt = 0;
            else
                app.opt = 1;
            end
        end

        % Callback function
        function TextAreaValueChanging(app, event)
            changingValue = event.Value;
            changingValue = app.Switch.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            resetVars(app);
            app.CalcX.Enable = "on";

            denumi = app.DenumText.Value;
            numi = app.NumiText.Value;
            app.denumi = str2num(denumi);
            app.numi = str2num(numi);

            calcZP(app);
            %app.LOG.Value = "Zeros = " + num2str(app.z) + "\nPoles = " + num2str(app.p) + "\nNumi = " + num2str(app.numi) + "\Denum = " + num2str(app.denumi);
            %app.LOG.Value = sprintf("Zeros = %s\nPoles = %s\nNumi = %s\nDenum = %s",num2str(app.z),num2str(app.p),num2str(app.numi),num2str(app.denumi));

            app.sys_zpk = zpk(app.z, app.p, 1,-1,'variable','z^-1'); % Create a transfer function using poles and zeros
            app.sys_tf = tf(app.sys_zpk); % Convert to transfer function form
            
            fig1 = figure('visible','off'); %should fix poping empty plot
            %plot Zero Poles Plot
            cla(app.zpplot);
            zpk_disp = evalc('app.sys_zpk');% Capture the output of disp function
            app.LOG.Value = zpk_disp; % Set the output string to the TextBox
            zplane(app.b, app.a, app.zpplot); grid(app.zpplot,"on"); %plot transfer function zeros\poles
            h = findobj(app.zpplot, 'Type', 'line');
            set(h(1),'DisplayName',"Unit Circle");  % Zeros in green
            set(h(2), 'Color', 'red','MarkerSize', 20,'DisplayName',"Poles");  % Zeros in green
            set(h(3), 'Color', 'green','MarkerSize', 20,'DisplayName',"Zeros");    % Poles in red
            legend(app.zpplot,"Location",'northwest');


            %Plot Zero Poles Freq W response
            cla(app.zpwplot);
            [hw,fw] = zerophase(app.b,app.a,1024,"whole");
            plot3(app.zpwplot,cos(fw),sin(fw),hw,'DisplayName','amplitude'); hold(app.zpwplot,"on");
            plot3(app.zpwplot,cos(fw),sin(fw),zeros(size(fw)),'--','DisplayName','unit circle');
            plot3(app.zpwplot,real(app.z),imag(app.z),zeros(size(app.z)),'o','DisplayName','zeros');
            plot3(app.zpwplot,real(app.p),imag(app.p),zeros(size(app.p)),'x','DisplayName','poles'); hold(app.zpwplot,"off");
            xlabel(app.zpwplot,"Real"); ylabel(app.zpwplot,"Imaginary"); view(app.zpwplot,35,40); grid(app.zpwplot,"on"); title(app.zpwplot,"Pole-Zero $\omega$ response",'interpreter','latex');
            h1 = findobj(app.zpwplot, 'Type', 'line');
            set(h1(1), 'Color', 'red','MarkerSize', 10);  % Zeros in green
            set(h1(2), 'Color', 'green','MarkerSize', 10);    % Poles in red
            legend(app.zpwplot,"Location",'northwest');


        end

        % Button pushed function: CalcX
        function CalcXButtonPushed(app, event)
            %enable settings
            app.Dval.Enable = "on"
            app.Dval.Visible = "on"

            app.Lval.Enable = "on"
            app.Lval.Visible = "on"

            app.Kval.Enable = "on"
            app.Kval.Visible = "on"

            app.alphabeta.Enable = "on"
            app.alphabeta.Visible = "on"

            app.WindowToggle.Enable = "on"
            app.WindowToggle.Visible = "on"    

            app.WindowType.Enable = "on"

            %synthesis x[n] using transfer function and w[n]

            app.N = app.NEditField.Value; %user gives signal length
            sigma0 = 1; %noise variance
            Wn = randn(1,app.N^2); %random normal noise

            %CREATE X[n]
            app.xn = filter(app.b,app.a,Wn(1:app.N));
            plot(app.synthX,app.xn,'DisplayName','X[n]');
            xlabel(app.synthX,"n")
            ylabel(app.synthX,"amplitude")
            title(app.synthX,"Synthisised signal X[n] using random noise W[n]")


            %REAL PSD
            [Hz,omega] = freqz(app.b,app.a,512); %plot REAL PSD
            real_psd = sigma0*abs(Hz).^2;
            plot(app.RealPSD,omega,real_psd,'r','DisplayName','Real PSD');
            xlabel(app.RealPSD,"\omega"); set(app.RealPSD,'XTick',0:pi/2:2*pi); set(app.RealPSD,'XTickLabel',{'0','\pi/2','\pi','1.5\pi','2\pi'});


            %PERIODOGRAM
            log_real_psd = 10*log10(real_psd);
            [psd_period,w] = periodogram(app.xn);
            plot(app.PSDest,w,10*log10(2*pi*psd_period),'DisplayName','Periodogram');
            hold(app.PSDest,"on")
            plot(app.PSDest,omega,log_real_psd,'r','DisplayName','Real PSD');
            xlabel(app.PSDest,"\omega"); set(app.PSDest,'XTick',0:pi/2:2*pi); set(app.PSDest,'XTickLabel',{'0','\pi/2','\pi','1.5\pi','2\pi'});
            legend(app.PSDest,"Location",'best')
            hold(app.PSDest,"off")

            selectWindow(app)
            updateK(app)


        end

        % Value changed function: WindowType
        function WindowTypeValueChanged(app, event)
           selectWindow(app)
        end

        % Value changed function: WindowToggle
        function WindowToggleValueChanged(app, event)
           selectWindow(app)
        end

        % Value changed function: Dval, Lval
        function LvalValueChanged(app, event)
            updateK(app)
            selectWindow(app)
        end

        % Value changed function: alphabeta
        function alphabetaValueChanged(app, event)
           selectWindow(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PSDEstimatorsbyDanielMatanUIFigure = uifigure('Visible', 'off');
            app.PSDEstimatorsbyDanielMatanUIFigure.Name = 'PSD Estimators by Daniel & Matan';
            app.zpplot = uiaxes(app.PSDEstimatorsbyDanielMatanUIFigure);
            title(app.zpplot, 'Zeros Poles Plot')
            xlabel(app.zpplot, 'Real Part')
            ylabel(app.zpplot, 'Imaginary Part')
            zlabel(app.zpplot, 'Z')
            app.zpwplot = uiaxes(app.PSDEstimatorsbyDanielMatanUIFigure);
            title(app.zpwplot, 'Zeros Poles Response')
            xlabel(app.zpwplot, 'Real')
            ylabel(app.zpwplot, 'Imaginary')
            zlabel(app.zpwplot, 'Amplitude')
            app.synthX = uiaxes(app.PSDEstimatorsbyDanielMatanUIFigure);
            title(app.synthX, 'Synthesized signal X[n] using random noise W[n]')
            xlabel(app.synthX, 'n')
            ylabel(app.synthX, 'Amplitude')
            zlabel(app.synthX, 'Z')
            app.RealPSD = uiaxes(app.PSDEstimatorsbyDanielMatanUIFigure);
            title(app.RealPSD, 'Real PSD - P_X{(w)}')
            xlabel(app.RealPSD, 'ω')
            ylabel(app.RealPSD, 'Amplitude')
            zlabel(app.RealPSD, 'Z')
            app.PSDest = uiaxes(app.PSDEstimatorsbyDanielMatanUIFigure);
            title(app.PSDest, 'PSD ESTIMATES')
            xlabel(app.PSDest, 'ω')
            ylabel(app.PSDest, 'Magnitude [dB]')
            zlabel(app.PSDest, 'Z')
            app.WindowsDispTime = uiaxes(app.PSDEstimatorsbyDanielMatanUIFigure);
            title(app.WindowsDispTime, 'Time Domain')
            xlabel(app.WindowsDispTime, 'n')
            ylabel(app.WindowsDispTime, 'Amplitude')
            zlabel(app.WindowsDispTime, 'Z')
            app.WindowsDispFreq = uiaxes(app.PSDEstimatorsbyDanielMatanUIFigure);
            title(app.WindowsDispFreq, 'Freq Domain')
            xlabel(app.WindowsDispFreq, 'ω')
            ylabel(app.WindowsDispFreq, 'Magnitude [dB]')
            zlabel(app.WindowsDispFreq, 'Z')
            app.Switch = uiswitch(app.PSDEstimatorsbyDanielMatanUIFigure, 'slider');
            app.Switch.Items = {'Transfer Function', 'Zeros\Poles'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Transfer Function';
            app.NumeratorEditFieldLabel = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumiText = uieditfield(app.PSDEstimatorsbyDanielMatanUIFigure, 'text');
            app.NumiText.Value = '1,0.3,0.8';
            app.LOG = uitextarea(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.LOG.Editable = 'off';
            app.DenominatorEditFieldLabel = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenumText = uieditfield(app.PSDEstimatorsbyDanielMatanUIFigure, 'text');
            app.DenumText.Value = '1';
            app.TFexample = uitextarea(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.TFexample.Value = {'Transfer Function: '; 'Numerator:  1'; 'Denominator: 1,0.3,0.8'; ''; 'Zeros\Poles: '; 'Numerator: 1'; 'Denominator: 0.5,-0.5'};
            app.CalculateButton = uibutton(app.PSDEstimatorsbyDanielMatanUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = '1. Calculate';
            app.NEditFieldLabel = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.PSDEstimatorsbyDanielMatanUIFigure, 'numeric');
            app.NEditField.Value = 100;
            app.CalcX = uibutton(app.PSDEstimatorsbyDanielMatanUIFigure, 'push');
            app.CalcX.ButtonPushedFcn = createCallbackFcn(app, @CalcXButtonPushed, true);
            app.CalcX.Enable = 'off';
            app.CalcX.Text = '2. Synthesis';
            app.WindowTypeDropDownLabel = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.WindowTypeDropDownLabel.Enable = 'off';
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowType = uidropdown(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.WindowType.Items = {'Hamming', 'Barthann', 'Blackman', 'Rectangle', 'Kaiser', 'Hann', 'Gausswin'};
            app.WindowType.ValueChangedFcn = createCallbackFcn(app, @WindowTypeValueChanged, true);
            app.WindowType.Enable = 'off';
            app.WindowType.Value = 'Hamming';
            app.WindowToggle = uiswitch(app.PSDEstimatorsbyDanielMatanUIFigure, 'slider');
            app.WindowToggle.Items = {'Symmetric', 'Periodic'};
            app.WindowToggle.ValueChangedFcn = createCallbackFcn(app, @WindowToggleValueChanged, true);
            app.WindowToggle.Enable = 'off';
            app.WindowToggle.Visible = 'off';
            app.WindowToggle.Value = 'Symmetric';
            app.LEditFieldLabel = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.Lval = uieditfield(app.PSDEstimatorsbyDanielMatanUIFigure, 'numeric');
            app.Lval.Limits = [2 Inf];
            app.Lval.ValueChangedFcn = createCallbackFcn(app, @LvalValueChanged, true);
            app.Lval.Enable = 'off';
            app.Lval.Visible = 'off';
            app.Lval.Value = 20;
            app.KLabel = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.KLabel.Text = 'K';
            app.Kval = uieditfield(app.PSDEstimatorsbyDanielMatanUIFigure, 'numeric');
            app.Kval.Editable = 'off';
            app.Kval.Enable = 'off';
            app.Kval.Visible = 'off';
            app.DLabel = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.DLabel.Text = 'D';
            app.Dval = uieditfield(app.PSDEstimatorsbyDanielMatanUIFigure, 'numeric');
            app.Dval.Limits = [1 Inf];
            app.Dval.ValueChangedFcn = createCallbackFcn(app, @LvalValueChanged, true);
            app.Dval.Enable = 'off';
            app.Dval.Visible = 'off';
            app.Dval.Value = 10;
            app.Label = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.Label.Text = 'α \ β';
            app.alphabeta = uieditfield(app.PSDEstimatorsbyDanielMatanUIFigure, 'numeric');
            app.alphabeta.Limits = [1 Inf];
            app.alphabeta.ValueChangedFcn = createCallbackFcn(app, @alphabetaValueChanged, true);
            app.alphabeta.Enable = 'off';
            app.alphabeta.Visible = 'off';
            app.alphabeta.Value = 1;
            app.XnlengthNEditFieldLabel_2 = uilabel(app.PSDEstimatorsbyDanielMatanUIFigure);
            app.XnlengthNEditFieldLabel_2.Text = 'X[n] Signal synthesis: ';
            % Show the figure after all components are created
            app.PSDEstimatorsbyDanielMatanUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSD_EstimatorV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PSDEstimatorsbyDanielMatanUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSDEstimatorsbyDanielMatanUIFigure)
        end
    end
end